classdef Desktop_Application_for_HV_Supply < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField                       matlab.ui.control.EditField
        RunTimeTextArea                 matlab.ui.control.TextArea
        RunTimeTextAreaLabel            matlab.ui.control.Label
        ClearLogButton                  matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TitleEditField                  matlab.ui.control.EditField
        TitleLabel                      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LogAreaTextArea                 matlab.ui.control.TextArea
        LogAreaTextAreaLabel            matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        RefreshPortsButton              matlab.ui.control.Button
        SerialPortsDropDown             matlab.ui.control.DropDown
        SerialPortsDropDownLabel        matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        FrequencyRange1030kHzEditField  matlab.ui.control.NumericEditField
        FrequencyRange1030kHzEditFieldLabel  matlab.ui.control.Label
        VoltageRange520kVEditField      matlab.ui.control.NumericEditField
        VoltageRange520kVEditFieldLabel  matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        ConnectionPort
        isConnected = false

        Timer
        SecondsCounter
        MinutesCounter
        HoursCounter
    end
    
    methods (Access = private)
        
        function log(app,msg)
            app.LogAreaTextArea.Value = [app.LogAreaTextArea.Value; string(msg) + newline];
        end
        
        function refreshPorts(app)
            
            ports = serialportlist("available");

            if isempty(ports)
                app.SerialPortsDropDown.Items = {'No Ports'};
            else
                app.SerialPortsDropDown.Items = ports;
            end
        end
        
        function ClockTimer(app, state)
            if strcmp(state, 'On')
                app.SecondsCounter = 0;
                app.MinutesCounter = 0;
                app.HoursCounter = 0;
                app.Timer = timer('TimerFcn', @TimerCall, 'ExecutionMode','fixedRate', 'Period', 1);
                start(app.Timer);
            else
                stop(app.Timer)
            end
         
            function TimerCall(~,~)
                TimeValue = sprintf('%d:%d:%d', app.HoursCounter,app.MinutesCounter,app.SecondsCounter);
                app.RunTimeTextArea.Value = TimeValue;
                app.SecondsCounter = app.SecondsCounter + 1;
                if(app.SecondsCounter == 60)
                    app.SecondsCounter = 0;
                    app.MinutesCounter = app.MinutesCounter + 1;
                end

                if(app.MinutesCounter == 60)
                    app.MinutesCounter = 0;
                    app.HoursCounter = app.HoursCounter + 1;
                end
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refreshPorts(app);
            log(app, "Application Started");
            app.RunTimeTextArea.HorizontalAlignment = 'right';
            app.RunTimeTextArea.Value = '0:0:0';

            app.EditField.BackgroundColor = [0.95, 0.95, 0.95];
            app.EditField.Visible = 'off';
            app.EditField.FontColor = [1, 0, 0];
            app.EditField.FontWeight = 'bold';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~app.isConnected
                port = app.SerialPortsDropDown.Value;
                
                try
                    app.ConnectionPort = serialport(port, 115200);
                    configureTerminator(app.ConnectionPort, "LF");

                    app.isConnected = true;
                    app.ConnectButton.Text = "Disconnect";

                    log(app, "Connected to " + port);
                    app.RunButton.BackgroundColor = [0, 1, 0];
                    %flush(port);

                catch ME
                    log(app, "Connection Error: " + ME.message);
                end

            else
                message = sprintf("%dV%dH", 0, 0);
                write(app.ConnectionPort, message, "char");
                app.StopButton.BackgroundColor = [1, 1, 1];
                app.RunButton.BackgroundColor = [0, 1, 0];
                app.EditField.Visible = 'off';

                clear app.serialObj
                app.isConnected = false;
                app.ConnectButton.Text = "Connect";
                log(app, "Disconnected");

                refreshPorts(app);
                
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~app.isConnected
                log(app, "Not Connected");
                return
            end

            V = app.VoltageRange520kVEditField.Value;
            F = app.FrequencyRange1030kHzEditField.Value;

            if (V >= 5) && (V <= 20) && (F >= 10) && (F <= 30)
                message = sprintf("%dV%dH", V, F);
                write(app.ConnectionPort, message, "char");

                log(app, "Sent: " + message);
                ValuesSent = sprintf("Values Sent: \nVoltage :%dkV \nFrequency :%dkHz\n", V, F);
                log(app, ValuesSent);
                ClockTimer(app, 'On');
                app.RunButton.BackgroundColor = [1, 1, 1];
                app.StopButton.BackgroundColor = [1, 0, 0];
                app.EditField.Visible = 'on';
                app.EditField.Value = 'WARNING: HV SUPPLY ENERGIZED!!!';
            elseif (V < 5) || (V > 20) && (F >= 10) && (F <= 30)
                log(app, "Voltage value out of range")
            elseif (F < 10) || (F > 30) && (V >= 5) && (V <= 20)
                log(app, "Frequency value out of range")
            else
                log(app, "Both voltage and frequency out of range")
            end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Name = app.TitleEditField.Value;
            notes = app.NotesTextArea.Value;
            NotesStr = strjoin(notes, newline);

            filename = [Name, '.txt'];
            f = fopen(filename, 'w');
            if f == -1
                log(app, "Could not open file for writing");
                return;
            end
            fprintf(f, "Notes: \n%s\n", NotesStr);
            fclose(f);
            log(app, filename + " created and notes saved");
        end

        % Button pushed function: RefreshPortsButton
        function RefreshPortsButtonPushed(app, event)
            refreshPorts(app);
            log(app,"Ports Refreshed");
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.LogAreaTextArea.Value = {''};
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            message = sprintf("%dV%dH", 0, 0);
            write(app.ConnectionPort, message, "char");
            
            ClockTimer(app, 'Off');
            app.StopButton.BackgroundColor = [1, 1, 1];
            app.RunButton.BackgroundColor = [0, 1, 0];
            log(app, "Power Supply Off");

            app.EditField.Visible = 'off';
            %log(app, "Sent: " + message);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.VoltageRange520kVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageRange520kVEditFieldLabel.Text = 'Voltage (Range:5-20kV)';
            app.VoltageRange520kVEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyRange1030kHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyRange1030kHzEditFieldLabel.Text = 'Frequency (Range:10-30kHz)';
            app.FrequencyRange1030kHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SerialPortsDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortsDropDownLabel.Text = 'Serial Ports';
            app.SerialPortsDropDown = uidropdown(app.UIFigure);
            app.RefreshPortsButton = uibutton(app.UIFigure, 'push');
            app.RefreshPortsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPortsButtonPushed, true);
            app.RefreshPortsButton.Text = 'Refresh Ports';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LogAreaTextAreaLabel = uilabel(app.UIFigure);
            app.LogAreaTextAreaLabel.Text = 'Log Area';
            app.LogAreaTextArea = uitextarea(app.UIFigure);
            app.LogAreaTextArea.Editable = 'off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.RunTimeTextAreaLabel = uilabel(app.UIFigure);
            app.RunTimeTextAreaLabel.Text = 'Run Time';
            app.RunTimeTextArea = uitextarea(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Desktop_Application_for_HV_Supply

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end